classdef MedicineDetectGUIS1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        PreProcessingTab             matlab.ui.container.Tab
        GreyscaleButton              matlab.ui.control.Button
        LaplacianButton              matlab.ui.control.Button
        ConnectedPass52985PASSEDFAIL3225Label_2  matlab.ui.control.Label
        SelectTechniqueLabel         matlab.ui.control.Label
        GreythresholdingButton       matlab.ui.control.Button
        BinarizationButton           matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        ProcessingTab                matlab.ui.container.Tab
        BatchInformationLabel        matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        BatchNo124MedicineDisprineLabel  matlab.ui.control.Label
        ConnectedPass52985PASSEDFAIL3225Label_3  matlab.ui.control.Label
        X956Y125RBG242536CPW10150148Label_3  matlab.ui.control.Label
        PASSEDLabel                  matlab.ui.control.Label
        PostProcessingTab            matlab.ui.container.Tab
        Image3                       matlab.ui.control.Image
        Image4_2                     matlab.ui.control.Image
        Image4_6                     matlab.ui.control.Image
        Image4_7                     matlab.ui.control.Image
        Image4_8                     matlab.ui.control.Image
        Image4_9                     matlab.ui.control.Image
        Image4_10                    matlab.ui.control.Image
        Image4_11                    matlab.ui.control.Image
        PassLabel                    matlab.ui.control.Label
        PassLabel_2                  matlab.ui.control.Label
        DefectedLabel                matlab.ui.control.Label
        PassLabel_3                  matlab.ui.control.Label
        PassLabel_4                  matlab.ui.control.Label
        PassLabel_5                  matlab.ui.control.Label
        DefectedLabel_2              matlab.ui.control.Label
        PassLabel_6                  matlab.ui.control.Label
        PassLabel_7                  matlab.ui.control.Label
        GraphLabel                   matlab.ui.control.Label
        IndexingWiseLabel            matlab.ui.control.Label
        Image4_14                    matlab.ui.control.Image
        Image4_15                    matlab.ui.control.Image
        NameLabel                    matlab.ui.control.Label
        DateTimeLabel                matlab.ui.control.Label
        HassanOsamaLabel             matlab.ui.control.Label
        SelectTabletDropDownLabel    matlab.ui.control.Label
        SelectTabletDropDown         matlab.ui.control.DropDown
        StartProcessButton           matlab.ui.control.Button
        PMLabel                      matlab.ui.control.Label
        SelectTabletDropDownLabel_2  matlab.ui.control.Label
        BrowseButton                 matlab.ui.control.Button
        Image4_12                    matlab.ui.control.Image
        Image4_13                    matlab.ui.control.Image
        Image4_5                     matlab.ui.control.Image
        EmergencyBreakAlarmButton_2  matlab.ui.control.Button
        StopProcessButton_2          matlab.ui.control.Button
        LogoutButton_2               matlab.ui.control.Button
        MedicineDetectionviaImageProcessingLabel_2  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LogoutButton_2
        function LogoutButton_2Pushed(app, event)
            
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            global image1;
            [filename ,pathname]=uigetfile({'*.*'},'File Selector');
            fullpathname=strcat(pathname,filename);
            image1=imread(fullpathname);
            imshow(image1,'Parent',app.UIAxes);
        end

        % Button pushed function: GreyscaleButton
        function GreyscaleButtonPushed(app, event)
            global image1;
            BW = image1;
            if (size(image1, 3) == 3)
                BW = rgb2gray(image1);
            end
            imshow(BW,'Parent',app.UIAxes);
        end

        % Button pushed function: BinarizationButton
        function BinarizationButtonPushed(app, event)
            global image1;
            BW = image1;
            if (size(image1, 3) == 3)   
                BW = rgb2gray(image1);
            end
            BW = im2double(BW);
            BW = imbinarize(BW);
            imshow(BW,'Parent',app.UIAxes);
        end

        % Button pushed function: GreythresholdingButton
        function GreythresholdingButtonPushed(app, event)
            global image1;
            BW = image1;
            if (size(image1, 3) == 3)
                BW = rgb2gray(image1);
            end
            %BW = im2double(BW);
            image = im2double(BW);
            
            c = 0;
            [x, y] = size(image);
            im = zeros(x, y);
            for i = 1 : x
                for j = 1 : y
                    im(i, j)=0;
                end
            end
            for i = 1 : x
                for j = 1 : y
                    c = c + image(i, j);
                end
            end
            threshold = c / (x * y);
            for i = 1 : x
                for j = 1 : y
                    if image(i, j) > threshold
                        im(i, j) = 0;
                    else
                        im(i, j) = 1;
                    end
                end
            end
            %figure;
            %imshow(y);
            imshow(im,'Parent',app.UIAxes);
            
            %level = graythresh(BW);
            %BW = imbinarize(BW, level);
            %imshow(BW,'Parent',app.UIAxes);
        end

        % Button pushed function: LaplacianButton
        function LaplacianButtonPushed(app, event)
            global image1;
            BW = image1;
            if (size(image1, 3) == 3)
                BW = rgb2gray(image1);
            end
        
            h=medfilt2(BW);
            h=medfilt2(h);
            h=medfilt2(h);

            lab=[0 1 0;1 -4 1;0 1 0];
            l2=[-1 -1 -1;-1 9 -1;-1 -1 -1];
            l3=[1 1 1;1 -8 1;1 1 1];
            r=filter2(l3,h,'same');
           % t=h-r;

           % h=medfilt2(t);
            h=medfilt2(h);
            h=medfilt2(h);
            
            
            %figure;
            %imshow(y);
            imshow(h,'Parent',app.UIAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PreProcessingTab = uitab(app.TabGroup);
            app.PreProcessingTab.Title = 'Pre-Processing';
            app.GreyscaleButton = uibutton(app.PreProcessingTab, 'push');
            app.GreyscaleButton.ButtonPushedFcn = createCallbackFcn(app, @GreyscaleButtonPushed, true);
            app.GreyscaleButton.Text = 'Greyscale';
            app.LaplacianButton = uibutton(app.PreProcessingTab, 'push');
            app.LaplacianButton.ButtonPushedFcn = createCallbackFcn(app, @LaplacianButtonPushed, true);
            app.LaplacianButton.Text = 'Laplacian';
            app.ConnectedPass52985PASSEDFAIL3225Label_2 = uilabel(app.PreProcessingTab);
            app.ConnectedPass52985PASSEDFAIL3225Label_2.Text = 'Connected+  Pass: 52      |       98.5%              [ PASSED ]                           FAIL:  3        |          2.25%';
            app.SelectTechniqueLabel = uilabel(app.PreProcessingTab);
            app.SelectTechniqueLabel.Text = 'Select Technique';
            app.GreythresholdingButton = uibutton(app.PreProcessingTab, 'push');
            app.GreythresholdingButton.ButtonPushedFcn = createCallbackFcn(app, @GreythresholdingButtonPushed, true);
            app.GreythresholdingButton.Text = 'Grey thresholding';
            app.BinarizationButton = uibutton(app.PreProcessingTab, 'push');
            app.BinarizationButton.ButtonPushedFcn = createCallbackFcn(app, @BinarizationButtonPushed, true);
            app.BinarizationButton.Text = 'Binarization';
            app.UIAxes = uiaxes(app.PreProcessingTab);
            title(app.UIAxes, '--')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ProcessingTab = uitab(app.TabGroup);
            app.ProcessingTab.Title = 'Processing';
            app.BatchInformationLabel = uilabel(app.ProcessingTab);
            app.BatchInformationLabel.Text = 'Batch Information';
            app.Image = uiimage(app.ProcessingTab);
            app.Image.ImageSource = 'Capture.PNG';
            app.BatchNo124MedicineDisprineLabel = uilabel(app.ProcessingTab);
            app.BatchNo124MedicineDisprineLabel.Text = {'Batch No: 124'; 'Medicine: Disprine'; ''; ''};
            app.ConnectedPass52985PASSEDFAIL3225Label_3 = uilabel(app.ProcessingTab);
            app.ConnectedPass52985PASSEDFAIL3225Label_3.Text = 'Connected+  Pass: 52      |       98.5%              [ PASSED ]                           FAIL:  3        |          2.25%';
            app.X956Y125RBG242536CPW10150148Label_3 = uilabel(app.ProcessingTab);
            app.X956Y125RBG242536CPW10150148Label_3.Text = 'X: 956        |       Y:125        |        RBG:24,25,36           |       CPW: 101,50,148 ';
            app.PASSEDLabel = uilabel(app.ProcessingTab);
            app.PASSEDLabel.Text = 'PASSED';
            app.PostProcessingTab = uitab(app.TabGroup);
            app.PostProcessingTab.Title = 'Post-Processing';
            app.Image3 = uiimage(app.PostProcessingTab);
            app.Image3.ImageSource = 'asdasdsadsa.png';
            app.Image4_2 = uiimage(app.PostProcessingTab);
            app.Image4_2.ImageSource = 'Capture.PNG';
            app.Image4_6 = uiimage(app.PostProcessingTab);
            app.Image4_6.ImageSource = 'Capture.PNG';
            app.Image4_7 = uiimage(app.PostProcessingTab);
            app.Image4_7.ImageSource = 'Capture.PNG';
            app.Image4_8 = uiimage(app.PostProcessingTab);
            app.Image4_8.ImageSource = 'Capture.PNG';
            app.Image4_9 = uiimage(app.PostProcessingTab);
            app.Image4_9.ImageSource = 'Capture.PNG';
            app.Image4_10 = uiimage(app.PostProcessingTab);
            app.Image4_10.ImageSource = 'Capture.PNG';
            app.Image4_11 = uiimage(app.PostProcessingTab);
            app.Image4_11.ImageSource = 'Capture.PNG';
            app.PassLabel = uilabel(app.PostProcessingTab);
            app.PassLabel.Text = '1     Pass';
            app.PassLabel_2 = uilabel(app.PostProcessingTab);
            app.PassLabel_2.Text = '2   Pass';
            app.DefectedLabel = uilabel(app.PostProcessingTab);
            app.DefectedLabel.Text = '3   Defected';
            app.PassLabel_3 = uilabel(app.PostProcessingTab);
            app.PassLabel_3.Text = '4   Pass';
            app.PassLabel_4 = uilabel(app.PostProcessingTab);
            app.PassLabel_4.Text = '5   Pass';
            app.PassLabel_5 = uilabel(app.PostProcessingTab);
            app.PassLabel_5.Text = '6  Pass';
            app.DefectedLabel_2 = uilabel(app.PostProcessingTab);
            app.DefectedLabel_2.Text = '7  Defected';
            app.PassLabel_6 = uilabel(app.PostProcessingTab);
            app.PassLabel_6.Text = '8   Pass';
            app.PassLabel_7 = uilabel(app.PostProcessingTab);
            app.PassLabel_7.Text = '9  Pass';
            app.GraphLabel = uilabel(app.PostProcessingTab);
            app.GraphLabel.Text = 'Graph';
            app.IndexingWiseLabel = uilabel(app.PostProcessingTab);
            app.IndexingWiseLabel.Text = 'Indexing Wise';
            app.Image4_14 = uiimage(app.PostProcessingTab);
            app.Image4_14.ImageSource = 'Capture.PNG';
            app.Image4_15 = uiimage(app.PostProcessingTab);
            app.Image4_15.ImageSource = 'Capture.PNG';
            app.NameLabel = uilabel(app.UIFigure);
            app.NameLabel.Text = 'Name:';
            app.DateTimeLabel = uilabel(app.UIFigure);
            app.DateTimeLabel.Text = 'Date/Time:';
            app.HassanOsamaLabel = uilabel(app.UIFigure);
            app.HassanOsamaLabel.Text = 'Hassan Osama';
            app.SelectTabletDropDownLabel = uilabel(app.UIFigure);
            app.SelectTabletDropDownLabel.Text = 'Select Tablet';
            app.SelectTabletDropDown = uidropdown(app.UIFigure);
            app.SelectTabletDropDown.Items = {'Disprine', 'Panadol', '', ''};
            app.SelectTabletDropDown.Value = '';
            app.StartProcessButton = uibutton(app.UIFigure, 'push');
            app.StartProcessButton.Text = 'Start Process';
            app.PMLabel = uilabel(app.UIFigure);
            app.PMLabel.Text = '25/12/2019 | 8:31 PM';
            app.SelectTabletDropDownLabel_2 = uilabel(app.UIFigure);
            app.SelectTabletDropDownLabel_2.Text = 'Add Medicine Image';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.Image4_12 = uiimage(app.UIFigure);
            app.Image4_12.ImageSource = 'ui-ux-designing-in-pune1.png';
            app.Image4_13 = uiimage(app.UIFigure);
            app.Image4_13.ImageSource = 'Man-Person-People-Avatar-User-Happy-512.png';
            app.Image4_5 = uiimage(app.UIFigure);
            app.Image4_5.Enable = 'off';
            app.Image4_5.ImageSource = '32b877ed4aa7778cc7d43ebb7d95a6f1.png';
            app.EmergencyBreakAlarmButton_2 = uibutton(app.UIFigure, 'push');
            app.EmergencyBreakAlarmButton_2.Text = 'Emergency Break & Alarm';
            app.StopProcessButton_2 = uibutton(app.UIFigure, 'push');
            app.StopProcessButton_2.Text = 'Stop Process';
            app.LogoutButton_2 = uibutton(app.UIFigure, 'push');
            app.LogoutButton_2.ButtonPushedFcn = createCallbackFcn(app, @LogoutButton_2Pushed, true);
            app.LogoutButton_2.Text = 'Logout';
            app.MedicineDetectionviaImageProcessingLabel_2 = uilabel(app.UIFigure);
            app.MedicineDetectionviaImageProcessingLabel_2.Text = 'Medicine Detection via Image Processing';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MedicineDetectGUIS1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end